classdef prediction_images < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        LoadPLSModelButton              matlab.ui.control.Button
        LoadHyperspectralImagesButton   matlab.ui.control.Button
        PropertytoPredictDropDownLabel  matlab.ui.control.Label
        PropertytoPredictDropDown       matlab.ui.control.DropDown
        GeneratePredictionImagesButton  matlab.ui.control.Button
        SaveImagesButton                matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        %info
        woodprop = 'MOE';
        envinames = {};
        inputFolderPath;
        bConvertToAbsorbance = true;
        params;
        
        %pls
        plsmodel;
        wavelength;
        
        %finished images
        IM_array = {};
        IM_index = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('..\envi\');
            addpath('altmany-export_fig-b1a7288\');
            app.params = set_parameters(app.woodprop, app.bConvertToAbsorbance);
        end

        % Value changed function: PropertytoPredictDropDown
        function PropertytoPredictDropDownValueChanged(app, event)
            value = app.PropertytoPredictDropDown.Value;
            app.woodprop = value;
            app.params = set_parameters(app.woodprop, app.bConvertToAbsorbance);
        end

        % Button pushed function: LoadPLSModelButton
        function LoadPLSModelButtonPushed(app, event)
            [plsfile,plspath] = uigetfile('*.csv');
            if(~isempty(plsfile))
                [app.plsmodel,app.wavelength] = read_pls_model_from_CVSfile(plspath,plsfile);
                %crops pls model to last column only if multiple sets of
                %coefficients are imported
                app.plsmodel = app.plsmodel(:,end);
            end
        end

        % Button pushed function: LoadHyperspectralImagesButton
        function LoadHyperspectralImagesButtonPushed(app, event)
            [app.envinames,app.inputFolderPath] = uigetfile('*.hyp','MultiSelect', 'on');
        end

        % Button pushed function: GeneratePredictionImagesButton
        function GeneratePredictionImagesButtonPushed(app, event)
            Ypred_avgs = zeros(1,length(app.envinames));
            for i=1:length(app.envinames)
                enviname             = fullfile(app.inputFolderPath, app.envinames{i});
                [IM, yout] = predict_image(enviname, app.plsmodel, app.params);
                
                app.IM_array{i} = IM;
                
                title(app.UIAxes,app.envinames(i),'Interpreter','none');
                visualize(app.UIAxes, IM, app.params.cmaplim, app.params.numColors);
                Ypred_avgs           = [Ypred_avgs, yout.avg_Y_valid];
                
                app.IM_index = i;
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if(app.IM_index == 1)
                app.IM_index = length(app.envinames);
            else
                app.IM_index = app.IM_index - 1;
            end
            
            title(app.UIAxes,app.envinames(app.IM_index),'Interpreter','none');
            visualize(app.UIAxes, app.IM_array{app.IM_index}, app.params.cmaplim, app.params.numColors);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if(app.IM_index == length(app.envinames))
                app.IM_index = 1;
            else
                app.IM_index = app.IM_index + 1;
            end
            
            title(app.UIAxes,app.envinames(app.IM_index),'Interpreter','none');
            visualize(app.UIAxes, app.IM_array{app.IM_index}, app.params.cmaplim, app.params.numColors);   
        end

        % Button pushed function: SaveImagesButton
        function SaveImagesButtonPushed(app, event)
            folder = uigetdir;            
            for i=1:length(app.IM_array)
                
                %set up uiaxes to be saved
                title(app.UIAxes,app.envinames(app.IM_index),'Interpreter','none');
                visualize(app.UIAxes, app.IM_array{i}, app.params.cmaplim, app.params.numColors);
                
                %%getframe not supported by UIAxes
                %-- workaround --
                % Create new figure and new axes
                figure
                axNew = axes; 
                % Copy all objects from UIAxes to new axis
                copyobj(app.UIAxes.Children, axNew)
                % Save all parameters of the UIAxes
                uiAxParams = get(app.UIAxes);
                uiAxParamNames = fieldnames(uiAxParams); 
                % Get list of editable params in new axis
                editableParams = fieldnames(set(axNew)); 
                % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
                badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
                badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition']; 
                uiAxGoodParams = rmfield(uiAxParams,badFields); 
                % set editable params on new axes
                set(axNew, uiAxGoodParams)
                
                %add in colorbar
                cb3 = colorbar(axNew,'EastOutside');
                
                %now save contents of new figure/axes               
                name = app.envinames(i);
                name = split(name,'.');
                name = name(1);
                filepath = strcat(folder, '\', name, '-predicted-',app.woodprop);
                
                export_fig(filepath{1}, '-png', '-m5');
                
                close
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.LoadPLSModelButton = uibutton(app.UIFigure, 'push');
            app.LoadPLSModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPLSModelButtonPushed, true);
            app.LoadPLSModelButton.Text = 'Load PLS Model';
            app.LoadHyperspectralImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadHyperspectralImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHyperspectralImagesButtonPushed, true);
            app.LoadHyperspectralImagesButton.Text = 'Load Hyperspectral Images';
            app.PropertytoPredictDropDownLabel = uilabel(app.UIFigure);
            app.PropertytoPredictDropDownLabel.Text = 'Property to Predict';
            app.PropertytoPredictDropDown = uidropdown(app.UIFigure);
            app.PropertytoPredictDropDown.Items = {'MOE', 'MOR', 'SG'};
            app.PropertytoPredictDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertytoPredictDropDownValueChanged, true);
            app.PropertytoPredictDropDown.Value = 'MOE';
            app.GeneratePredictionImagesButton = uibutton(app.UIFigure, 'push');
            app.GeneratePredictionImagesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePredictionImagesButtonPushed, true);
            app.GeneratePredictionImagesButton.Text = 'Generate Prediction Images';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesButtonPushed, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prediction_images

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end