classdef imageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadButton              matlab.ui.control.Button
        Wavelength1SliderLabel  matlab.ui.control.Label
        Wavelength1Slider       matlab.ui.control.Slider
        Wavelength2SliderLabel  matlab.ui.control.Label
        Wavelength2Slider       matlab.ui.control.Slider
        Wavelength3SliderLabel  matlab.ui.control.Label
        Wavelength3Slider       matlab.ui.control.Slider
        UIAxes                  matlab.ui.control.UIAxes
        PositionLabel           matlab.ui.control.Label
        PositionSlider          matlab.ui.control.Slider
        VerticalCheckBox        matlab.ui.control.CheckBox
        DivisionsSpinnerLabel   matlab.ui.control.Label
        DivisionsSpinner        matlab.ui.control.Spinner
        WidthSpinnerLabel       matlab.ui.control.Label
        WidthSpinner            matlab.ui.control.Spinner
        BinPixelsButton         matlab.ui.control.Button
        MaskButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        sample_norm;
        vertical = false;
        binning = false;
        pathName;
    end
    
    methods (Access = private)
                
        function loaddata(app,pathName)
            app.pathName = pathName;
            [HS,Dref,Wref,wavelength] = HS_SpecImport(pathName);
            wr_av = mean(Dref); % calc mean dark reference reflectance
            dr_av = mean(Wref); % calc mean white reference reflectance
            
            % apply normalization to raw data using white and dark reference values
            % each 'sensor' (pixel, or row of pixels) is normalized using the white and
            % dark reflectance values for that sensor.
            % Illumination across the white reference bar is not even, so raw data will
            % vary in reflectance across the 'width' of the image. Compare raw and
            % normalized reflectance images and you'll see what I mean.
                  
            app.sample_norm = (HS - dr_av) ./ (wr_av - dr_av);

            % plot the normalized data
            % this example code is plotting a subset of x,y coordinates and the 200th
            % wavelength from the hypercube (x,y,z).
            updateimage(app);                     
        end
        
        
        function updateimage(app) 
            imagesc(app.UIAxes, app.sample_norm(:,:,[int16(app.Wavelength1Slider.Value) int16(app.Wavelength2Slider.Value) int16(app.Wavelength3Slider.Value)]));
            segment(app);
        end
        
        %draws line through image and creates boxes along line
        function segment(app)
            %image size
            yl = size(app.sample_norm(:,1,1));
            yl = yl(1);
            xl = size(app.sample_norm(1,:,1));
            xl = xl(2);
           
            %draw line
            scale = app.PositionSlider.Value;
            if (app.vertical)
                l = line(app.UIAxes, [xl*(scale), xl*(scale)], [0, yl]);
            else
                l = line(app.UIAxes, [0, xl], [yl*(scale), yl*(scale)]);
            end
            l.Color = 'yellow';
            l.LineWidth = 1;
            
            %draw boxes
            width = app.WidthSpinner.Value;
            n = app.DivisionsSpinner.Value;
            app.sample_norm(:,:,225) = -1;  %track box num
            
            for i = 0:n-1
                if(app.vertical)
                    box = [(xl*scale - width/2), (xl*scale + width/2), (xl*scale + width/2), (xl*scale - width/2);
                           (i*yl/n), (i*yl/n), ((i+1)*yl/n), ((i+1)*yl/n)];
                else
                    box = [(i*xl/n), (i*xl/n), ((i+1)*xl/n), ((i+1)*xl/n); 
                           (yl*scale - width/2), (yl*scale + width/2), (yl*scale + width/2), (yl*scale - width/2)];
                end

                drawpoly(app, box);

                %track box num
                if(app.binning)
                    for y = 1:yl
                        for x = 1:xl
                            if(inpolygon(x,y,box(1,:), box(2,:)))
                                app.sample_norm(y,x,225) = i;
                            end
                        end
                    end
                end
            end
        end
        
        %drawpolygon() function does not support uiaxes object
        %takes a 2 x n matrix of the form [x1 x2 x3; y1 y2 y3]
        function drawpoly(app, poly)
            n = size(poly);
            n = n(2);
            
            for i = 1:n-1               
                line(app.UIAxes,poly(1,i:i+1), poly(2,i:i+1))
            end
            
            line(app.UIAxes,[poly(1,n), poly(1,1)], [poly(2,n), poly(2,1)])
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            defaultData = 'C:\Users\Asa\Desktop\Wood Science\Data\Laurie_core_test_2019-05-29_21-35-11\capture\';
            loaddata(app, defaultData);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetdir dialog
            captureDir = uigetdir();
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(captureDir))
               loaddata(app, strcat(captureDir,'\'));
            end
        end

        % Value changed function: Wavelength1Slider
        function Wavelength1SliderValueChanged(app, event)
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end
        end

        % Value changed function: Wavelength2Slider
        function Wavelength2SliderValueChanged(app, event)
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end          
        end

        % Value changed function: Wavelength3Slider
        function Wavelength3SliderValueChanged(app, event)
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end          
        end

        % Callback function
        function ButtonPushed(app, event)
            p = app.UIAxes.CurrentPoint;
            p(1,1:2)
        end

        % Value changed function: VerticalCheckBox
        function VerticalCheckBoxValueChanged(app, event)
            value = app.VerticalCheckBox.Value;
            app.vertical = value;
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end 
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end 
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end             
        end

        % Value changed function: DivisionsSpinner
        function DivisionsSpinnerValueChanged(app, event)
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end             
        end

        % Callback function
        function ColorBoxCheckBoxValueChanged(app, event)
            value = app.ColorBoxCheckBox.Value;
            app.colored = value;
            if (size(app.sample_norm) > 0) 
                updateimage(app);            
            end  
        end

        % Button pushed function: BinPixelsButton
        function BinPixelsButtonPushed(app, event)
            app.binning  = ~app.binning;
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            I = app.sample_norm(:,:,1);
            
            mask = zeros(size(I));
            mask(25:end-25,25:end-25) = 1;
            mask = activecontour(I,mask,300);
            mask = ~mask;
            %cut off noisy edges
            mask(:,1:50) = 0;
            mask(:,end-50:end) = 0;
            
            %apply mask
            app.sample_norm = app.sample_norm .* mask;
            updateimage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = {'Load'; ''};
            app.Wavelength1SliderLabel = uilabel(app.UIFigure);
            app.Wavelength1SliderLabel.Text = {'Wavelength 1'; ''};
            app.Wavelength1Slider = uislider(app.UIFigure);
            app.Wavelength1Slider.Limits = [1 224];
            app.Wavelength1Slider.ValueChangedFcn = createCallbackFcn(app, @Wavelength1SliderValueChanged, true);
            app.Wavelength1Slider.Value = 1;
            app.Wavelength2SliderLabel = uilabel(app.UIFigure);
            app.Wavelength2SliderLabel.Text = {'Wavelength 2'; ''};
            app.Wavelength2Slider = uislider(app.UIFigure);
            app.Wavelength2Slider.Limits = [1 224];
            app.Wavelength2Slider.ValueChangedFcn = createCallbackFcn(app, @Wavelength2SliderValueChanged, true);
            app.Wavelength2Slider.Value = 1;
            app.Wavelength3SliderLabel = uilabel(app.UIFigure);
            app.Wavelength3SliderLabel.Text = 'Wavelength 3';
            app.Wavelength3Slider = uislider(app.UIFigure);
            app.Wavelength3Slider.Limits = [1 224];
            app.Wavelength3Slider.ValueChangedFcn = createCallbackFcn(app, @Wavelength3SliderValueChanged, true);
            app.Wavelength3Slider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.PositionSlider = uislider(app.UIFigure);
            app.PositionSlider.Limits = [0 1];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.PositionSlider.Value = 0.5;
            app.VerticalCheckBox = uicheckbox(app.UIFigure);
            app.VerticalCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerticalCheckBoxValueChanged, true);
            app.VerticalCheckBox.Text = 'Vertical';
            app.DivisionsSpinnerLabel = uilabel(app.UIFigure);
            app.DivisionsSpinnerLabel.Text = 'Divisions';
            app.DivisionsSpinner = uispinner(app.UIFigure);
            app.DivisionsSpinner.ValueChangedFcn = createCallbackFcn(app, @DivisionsSpinnerValueChanged, true);
            app.DivisionsSpinner.Value = 5;
            app.WidthSpinnerLabel = uilabel(app.UIFigure);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.UIFigure);
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 20;
            app.BinPixelsButton = uibutton(app.UIFigure, 'push');
            app.BinPixelsButton.ButtonPushedFcn = createCallbackFcn(app, @BinPixelsButtonPushed, true);
            app.BinPixelsButton.Text = 'Bin Pixels';
            app.MaskButton = uibutton(app.UIFigure, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end