classdef image_processing_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LoadButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        PositionLabel          matlab.ui.control.Label
        PositionSlider         matlab.ui.control.Slider
        VerticalCheckBox       matlab.ui.control.CheckBox
        DivisionsSpinnerLabel  matlab.ui.control.Label
        DivisionsSpinner       matlab.ui.control.Spinner
        WidthSpinnerLabel      matlab.ui.control.Label
        WidthSpinner           matlab.ui.control.Spinner
        UIAxes2                matlab.ui.control.UIAxes
        StripDividerButton     matlab.ui.control.StateButton
        EdgeFinderButton       matlab.ui.control.StateButton
        ExportButton           matlab.ui.control.Button
        BulkProcessingButton   matlab.ui.control.Button
        LeftButton             matlab.ui.control.Button
        RightButton            matlab.ui.control.Button
        Status                 matlab.ui.control.TextArea
    end

    properties (Access = private)
        %image data
        sample;                     %holds current data
        sample_array = {};          %holds multiple samples for bulk processing
        sample_index = 1;           %tracks current sample for bulk
        spectrum = zeros(1,224);    %holds current avg spectrum
        spectrum_array = {};        %holds multiple spectra for bulk export
        rows;
        cols;
        pathname;

        
        %flags
        vertical = false;
        strip = false;
        autoroi = false;
        bulk = false;
        
        %masking & ROI
        roiboundary;
        roiboundary_array = {};
        sample_masked;
        sample_masked_array = {};
        
    end
    
    methods (Access = private)
        function findboundary(app)
            %create mask
            I = app.sample(:,:,1);
            mask = zeros(size(I));
            mask(25:end-25,25:end-25) = 1;
            mask = activecontour(I,mask,300);
            app.sample_masked = app.sample .* mask;
        
            %create boundary
            app.roiboundary = bwboundaries(mask);
        end
        
        function loaddata(app,path)
            app.pathname = path;
            
            %data import and normalization
            [HS,Dref,Wref,wavelength] = HS_SpecImport(path);
            wr_av = mean(Dref); % calc mean dark reference reflectance
            dr_av = mean(Wref); % calc mean white reference reflectance
            app.sample = (HS - dr_av) ./ (wr_av - dr_av);
            
            %cropping out edges - MOVE TO UPDATEIMAGE
            app.sample = app.sample(:,30:end-30,:);
            
            %do roi
            if (app.autoroi)
                findboundary(app);
            end
            
            if(~app.bulk)
                app.Status.Value = 'Loading finished.';
            end
            
            % plot/segment the normalized data & calc average spectrum
            updateimage(app);
        end
        
        
        function updateimage(app)
            %clear axes and display image 
            cla(app.UIAxes)            
            imagesc(app.UIAxes, app.sample(:,:,[1, 100, 200]));
            hold(app.UIAxes,'on')
            segment(app);
        end
        
        %Segment image with strip or auto roi, create spectrum plot
        function segment(app)
            [app.rows, app.cols] = size(app.sample(:,:,1)); %get image size
            if (app.autoroi)
                %plot roi
                for k = 1:length(app.roiboundary)
                    boundary = app.roiboundary{k};
                    plot(app.UIAxes, boundary(:,2), boundary(:,1),'r','LineWidth', 1.5);
                end
                
                %create masked avg spectrum - much faster than looping but
                %I don't understand how this works
                sample_list = reshape(app.sample_masked, [app.rows*app.cols,224]);
                sample_list = sample_list';
                [ii,~,v] = find(sample_list);
                app.spectrum = accumarray(ii,v,[],@mean);
                app.spectrum = app.spectrum';
            end
            
            if (app.strip)
                stripsegments(app);
            end
            
            if (~app.autoroi && ~app.strip)
                %create full avg spectrum
                sample_list = reshape(app.sample, [app.rows*app.cols,224]);
                app.spectrum = mean(sample_list);
            end
                 
            %plot and save spectrum
            cla(app.UIAxes2);   %clear plot
            app.spectrum_array{app.sample_index} = app.spectrum;
            
            if(app.strip) %multiple spectra
                hold(app.UIAxes2,'on')
                for i = 1:app.DivisionsSpinner.Value
                    plot(app.UIAxes2, app.spectrum(i,:));
                end
            else
                plot(app.UIAxes2, app.spectrum, 'r');
            end
            xt=arrayfun(@num2str,uint16((get(app.UIAxes2,'xtick')*3.5045) + 935),'un',0);
            set(app.UIAxes2,'xticklabel',xt)
        end
        
        %draws strips and boxes. 
        function stripsegments(app)           
            %draw boxes and get spectra
            width = app.WidthSpinner.Value;
            n = app.DivisionsSpinner.Value;
            scale = 1 - app.PositionSlider.Value;
            
            app.spectrum = zeros(n,224);
            colors = ['b','r','y','m','g','c','k']; %colors in the order they are chosen by plot()
            
            for i = 0:n-1
                if(app.vertical)
                    box = [(app.cols*scale - width/2), (app.cols*scale + width/2), (app.cols*scale + width/2), (app.cols*scale - width/2);
                           (i*app.rows/n), (i*app.rows/n), ((i+1)*app.rows/n), ((i+1)*app.rows/n)];
                else
                    box = [(i*app.cols/n), (i*app.cols/n), ((i+1)*app.cols/n), ((i+1)*app.cols/n); 
                           (app.rows*scale - width/2), (app.rows*scale + width/2), (app.rows*scale + width/2), (app.rows*scale - width/2)];
                end
                
                %draw current box - color same as their avg spectrum plot
                colornum = mod(i,length(colors)) + 1;
                c = colors(colornum);
                drawpoly(app, box, c);
                
                %get avg spectrum inside current box
                box(box == 0) = 1;
                box = uint16(box);
                sample_list = app.sample(box(2,1):box(2,3),box(1,1):box(1,3),:);
                [brows,bcols] = size(sample_list(:,:,1));
                sample_list = reshape(sample_list, [brows*bcols,224]);
                app.spectrum(i+1,:) = mean(sample_list);
            end
        end
        
        %drawpolygon() function does not support uiaxes object
        %takes a 2 x n matrix of the form [x1 x2 x3; y1 y2 y3]
        function drawpoly(app, poly, c)
            n = size(poly);
            n = n(2);
            
            for i = 1:n-1               
                line(app.UIAxes,poly(1,i:i+1), poly(2,i:i+1), 'Color', c)
            end
            
            line(app.UIAxes,[poly(1,n), poly(1,1)], [poly(2,n), poly(2,1)], 'Color', c)
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            defaultData = 'C:\Users\Asa\Desktop\Wood Science\Data\Asa_Knots_1_2019-06-04_17-54-49\capture\';
            %set axes color order to match predefined colors
            co = [0 0 1;
                  1 0 0;
                  1 1 0;
                  1 0 1;
                  0 1 0;
                  0 1 1;
                  0 0 0];
            set(groot,'defaultAxesColorOrder',co)
            loaddata(app, defaultData);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetdir dialog
            captureDir = uigetdir();
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(captureDir))
               loaddata(app, strcat(captureDir,'\'));
            end
        end

        % Value changed function: VerticalCheckBox
        function VerticalCheckBoxValueChanged(app, event)
            value = app.VerticalCheckBox.Value;
            app.vertical = value;
            if (size(app.sample) > 0) 
                updateimage(app);            
            end 
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            if (size(app.sample) > 0) 
                updateimage(app);            
            end 
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            if (size(app.sample) > 0) 
                updateimage(app);            
            end             
        end

        % Value changed function: DivisionsSpinner
        function DivisionsSpinnerValueChanged(app, event)
            if (size(app.sample) > 0) 
                updateimage(app);            
            end             
        end

        % Value changed function: StripDividerButton
        function StripDividerButtonValueChanged(app, event)
            value = app.StripDividerButton.Value;
            app.strip = value;
            app.EdgeFinderButton.Value = 0;
            app.autoroi = 0;
            
            updateimage(app);             
        end

        % Value changed function: EdgeFinderButton
        function EdgeFinderButtonValueChanged(app, event)
            %flagging
            value = app.EdgeFinderButton.Value;
            app.autoroi = value;
            app.StripDividerButton.Value = 0;
            app.strip = 0;
            
            if(app.autoroi == 1)
                app.Status.Value = 'Calculating edges...';
                pause(0.01);
            end
            
            if(app.bulk)
                for i = 1:length(app.sample_array)
                    app.sample = app.sample_array(i);
                    app.sample_index = i;
                    findboundary(app);
                end
            else
                findboundary(app);
            end
            app.Status.Value = 'Edge finder finished.';
            
            updateimage(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            name = strsplit(app.pathname, '\');
            name = name(end-2);
            name = strcat('\',name,'.txt');
            [name,path] = uiputfile(name);
            path = strcat(path,name);
            
            if (app.bulk)
                dlmwrite(path,app.spectrum_array{1},'delimiter','\t');
                for i = 2:length(app.spectrum_array)
                    dlmwrite(path,app.spectrum_array{i},'-append','delimiter','\t','roffset',1);
                end
                app.Status.Value = 'Bulk export complete.';
            else 
                writematrix(app.spectrum,path);
                app.Status.Value = 'Export complete.';
            end           
        end

        % Button pushed function: BulkProcessingButton
        function BulkProcessingButtonPushed(app, event)
            app.bulk = true;
        
            app.spectrum_array = {};
            app.sample_array = {};
            %app.sample_array{1} = app.sample;
            %app.spectrum_array{1} = app.spectrum;
            
            %Open files - select major directory, not \capture\
            bulk_files = uigetfile_n_dir();
            for i = 1:length(bulk_files)
                app.Status.Value = strcat('Loading file ', num2str(i), ' of ', num2str(length(bulk_files)));
                pause(0.01)
                loaddata(app, strcat(bulk_files{i},'\capture\'));
                app.sample_array{i} = app.sample;
                app.spectrum_array{i} = app.spectrum;
                if(app.autoroi)
                    app.roiboundary_array{i} = app.roiboundary;
                    app.sample_masked_array{i} = app.sample_masked;
                end
            end
            app.sample_index = length(app.sample_array);
            app.Status.Value = 'Bulk load complete.';
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if(app.bulk)    
                app.sample_index = app.sample_index - 1;
                if (app.sample_index == 0)
                    app.sample_index = length(app.sample_array);
                end
                app.sample = app.sample_array{app.sample_index};
                app.spectrum = app.spectrum_array{app.sample_index};
                if(app.autoroi)
                    app.roiboundary = app.roiboundary_array{app.sample_index};
                    app.sample_masked = app.sample_masked_array{app.sample_index};
                end
                updateimage(app);
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if(app.bulk)    
                app.sample_index = app.sample_index + 1;
                if (app.sample_index > length(app.sample_array))
                    app.sample_index = 1;
                end
                app.sample = app.sample_array{app.sample_index};
                app.spectrum = app.spectrum_array{app.sample_index};
                if(app.autoroi)
                    app.roiboundary = app.roiboundary_array{app.sample_index};
                    app.sample_masked = app.sample_masked_array{app.sample_index};
                end
                updateimage(app);
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = {'Load'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.PositionSlider = uislider(app.UIFigure);
            app.PositionSlider.Limits = [0 1];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.PositionSlider.Value = 0.5;
            app.VerticalCheckBox = uicheckbox(app.UIFigure);
            app.VerticalCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerticalCheckBoxValueChanged, true);
            app.VerticalCheckBox.Text = 'Vertical';
            app.DivisionsSpinnerLabel = uilabel(app.UIFigure);
            app.DivisionsSpinnerLabel.Text = 'Divisions';
            app.DivisionsSpinner = uispinner(app.UIFigure);
            app.DivisionsSpinner.ValueChangedFcn = createCallbackFcn(app, @DivisionsSpinnerValueChanged, true);
            app.DivisionsSpinner.Value = 5;
            app.WidthSpinnerLabel = uilabel(app.UIFigure);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.UIFigure);
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 20;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Average Spectrum')
            xlabel(app.UIAxes2, 'Wavelength (nm)')
            ylabel(app.UIAxes2, 'Reflectance')
            app.StripDividerButton = uibutton(app.UIFigure, 'state');
            app.StripDividerButton.ValueChangedFcn = createCallbackFcn(app, @StripDividerButtonValueChanged, true);
            app.StripDividerButton.Text = 'Strip Divider';
            app.EdgeFinderButton = uibutton(app.UIFigure, 'state');
            app.EdgeFinderButton.ValueChangedFcn = createCallbackFcn(app, @EdgeFinderButtonValueChanged, true);
            app.EdgeFinderButton.Text = {'Edge Finder'; ''};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.BulkProcessingButton = uibutton(app.UIFigure, 'push');
            app.BulkProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @BulkProcessingButtonPushed, true);
            app.BulkProcessingButton.Text = 'Bulk Processing';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>';
            app.Status = uitextarea(app.UIFigure);
            app.Status.Value = {'Status'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_processing_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end